classdef crane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RemoveCommandButton            matlab.ui.control.Button
        StartAnimationButton           matlab.ui.control.Button
        YourcommandsListBox            matlab.ui.control.ListBox
        YourcommandsListBoxLabel       matlab.ui.control.Label
        AddCommandButton               matlab.ui.control.Button
        ParameterEditField             matlab.ui.control.NumericEditField
        ParameterEditFieldLabel        matlab.ui.control.Label
        SelectCommandsDropDown         matlab.ui.control.DropDown
        SelectCommandsDropDownLabel    matlab.ui.control.Label
        CRANEANIMATIONSDESIGNINGLabel  matlab.ui.control.Label
        AnimationAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         commands = {};                % Command list
        params = struct([]);          % Parameters for commands
        current_state = struct();     % Current state of crane
        placed_objects = [];          % Placed objects tracking
        
        % Animation constants
        crane_arm_length = 10;
        object_width = 2;
        object_height = 1.2;
        cable_initial_length = 6;
        cable_final_length = 2;
        cable_extended_length = 9;
        crane_base_width = 2;
        frames_per_command = 50;
        
        % Drawing resources
        unit_rect
        unit_circle
    end
    
    methods (Access = private)
        
            function initializeAnimationResources(app)
            % Create unit rectangle and circle for transformations
            app.unit_rect = [0 1 1 0; 0 0 1 1; 1 1 1 1];
            
            n_circle = 20;
            theta = linspace(0, 2*pi, n_circle);
            app.unit_circle = [0.5*cos(theta); 0.5*sin(theta); ones(1, n_circle)];
        end
        
        function runAnimation(app)
            % Main animation function
            if isempty(app.commands)
                return;
            end
            
            % Animation parameters
            frame_count = app.frames_per_command * length(app.commands);
            
            % Reset animation state
            state = struct();
            state.arm_angle = 0;
            state.arm_scale = 1;
            state.swing = false;
            state.tilt = 0;
            state.displace = 0;
            state.pillar_height = 8;
            cable_length = app.cable_initial_length;
            
            % Building foundation coordinates
            foundation_x = 5;
            foundation_width = 5;
            ground_y = 0;
            
            % Track if an object is being held
            object_held = true;
            
            % Disable UI during animation
            app.SelectCommandsDropDown.Enable = 'off';
            app.ParameterEditField.Enable = 'off';
            app.AddCommandButton.Enable = 'off';
            app.RemoveCommandButton.Enable = 'off';
            app.StartAnimationButton.Enable = 'off';
            
            % Setup axes
            cla(app.AnimationAxes);
            axis(app.AnimationAxes, 'equal');
            axis(app.AnimationAxes, [-5 25 -1 18]);
            hold(app.AnimationAxes, 'on');
            
            for t = 1:frame_count
                cla(app.AnimationAxes);
                
                current_command_index = ceil(t / app.frames_per_command);
                local_frame = mod(t-1, app.frames_per_command) + 1;
                
                % Copy previous state unless changing
                cmd_type = app.params(current_command_index).type;
                cmd_info = app.params(current_command_index);
                
                progress = local_frame/app.frames_per_command;
                if progress > 1, progress = 1; end
                
                % Draw background sky
                app.AnimationAxes.Color = [0.8 0.95 1];
                
                % Draw sun
                sun_x = 20;
                sun_y = 16;
                sun_radius = 1.2;
                S_sun = [sun_radius, 0, 0; 0, sun_radius, 0; 0, 0, 1];
                T_sun = [1, 0, sun_x; 0, 1, sun_y; 0, 0, 1];
                sun = T_sun * S_sun * app.unit_circle;
                fill(app.AnimationAxes, sun(1,:), sun(2,:), [1 0.9 0.2]);
                
                % Clouds
                cloud_centers = [5 15; 14 17; 8 12];
                for i = 1:size(cloud_centers, 1)
                    cloud_x = cloud_centers(i, 1);
                    cloud_y = cloud_centers(i, 2);
                    for j = 1:3
                        radius = 0.6 + 0.2 * rand();
                        offset_x = 0.7 * j;
                        offset_y = 0.2 * sin(j);
                        S_cloud = [radius, 0, 0; 0, radius, 0; 0, 0, 1];
                        T_cloud = [1, 0, cloud_x + offset_x; 0, 1, cloud_y + offset_y; 0, 0, 1];
                        cloud = T_cloud * S_cloud * app.unit_circle;
                        fill(app.AnimationAxes, cloud(1,:), cloud(2,:), [0.95 0.95 0.95]);
                    end
                end
                
                % Draw ground
                ground_width = 30;
                ground_color = [0.6 0.5 0.3];
                ground_height = 1;
                fill(app.AnimationAxes, [-5 ground_width ground_width -5], ...
                     [ground_y ground_y ground_y-ground_height ground_y-ground_height], ground_color);
                
                % Draw building foundation
                fill(app.AnimationAxes, [foundation_x foundation_x+foundation_width foundation_x+foundation_width foundation_x], ...
                     [ground_y ground_y ground_y+1 ground_y+1], [0.7 0.7 0.7]);
                
                % Update state based on current command
                switch cmd_type
                    case 'rotatearm'
                        state.arm_angle = cmd_info.start + progress * (cmd_info.target - cmd_info.start);
                    case 'extendarm'
                        state.arm_scale = cmd_info.start + progress * (cmd_info.target - cmd_info.start);
                    case 'swingobject'
                        state.swing = true;
                    case 'tiltobject'
                        state.tilt = cmd_info.start + progress * (cmd_info.target - cmd_info.start);
                    case 'raisepillar'
                        state.pillar_height = cmd_info.start + progress * (cmd_info.target - cmd_info.start);
                    case 'displace'
                        state.displace = cmd_info.start + progress * (cmd_info.target - cmd_info.start);
                end
                
                % Cable adjustment for lift/place
                if strcmp(app.commands{current_command_index}, 'lift')
                    cable_length = app.cable_initial_length - progress * (app.cable_initial_length - app.cable_final_length);
                elseif strcmp(app.commands{current_command_index}, 'place')
                    % Modified place command - extend cable more
                    cable_length = app.cable_final_length + progress * (app.cable_extended_length - app.cable_final_length);
                    
                    % If we're at the end of placing, create a placed object
                    if progress > 0.95 && object_held
                        place_x = cmd_info.place_x;
                        
                        % Add to placed objects list
                        new_placed = struct();
                        new_placed.x = place_x;
                        new_placed.y = ground_y + 0.6; % Place slightly above ground
                        new_placed.width = app.object_width;
                        new_placed.height = app.object_height;
                        new_placed.color = [0.8 0.2 0.2];
                        app.placed_objects = [app.placed_objects, new_placed];
                        
                        % Object is no longer held but still visible
                        object_held = false;
                    end
                end
                
                % Draw placed objects on ground
                if ~isempty(app.placed_objects)
                    for i = 1:length(app.placed_objects)
                        obj = app.placed_objects(i);
                        S_obj = [obj.width, 0, 0; 0, obj.height, 0; 0, 0, 1];
                        T_obj = [1, 0, obj.x - obj.width/2; 0, 1, obj.y; 0, 0, 1];
                        box = T_obj * S_obj * app.unit_rect;
                        fill(app.AnimationAxes, box(1,:), box(2,:), obj.color);
                    end
                end
                
                % Draw crane base
                S_base = [app.crane_base_width, 0, 0; 0, 1, 0; 0, 0, 1];
                T_base = [1, 0, 0; 0, 1, ground_y; 0, 0, 1];
                base = T_base * S_base * app.unit_rect;
                fill(app.AnimationAxes, base(1,:), base(2,:), [0.5 0.5 0.5]);
                
                % Pillar
                S_pillar = [1, 0, 0; 0, state.pillar_height, 0; 0, 0, 1];
                T_pillar = [1, 0, 0.5 * app.crane_base_width - 0.5; 0, 1, ground_y + 1; 0, 0, 1];
                pillar = T_pillar * S_pillar * app.unit_rect;
                fill(app.AnimationAxes, pillar(1,:), pillar(2,:), [0.4 0.4 0.4]);
                
                % Arm
                angle_rad = deg2rad(state.arm_angle);
                R_arm = [cos(angle_rad), -sin(angle_rad), 0; sin(angle_rad), cos(angle_rad), 0; 0, 0, 1];
                S_arm = [app.crane_arm_length * state.arm_scale, 0, 0; 0, 1, 0; 0, 0, 1];
                T_arm = [1, 0, 0.5 * app.crane_base_width - 0.5; 0, 1, ground_y + state.pillar_height + 1; 0, 0, 1];
                arm = T_arm * R_arm * S_arm * app.unit_rect;
                fill(app.AnimationAxes, arm(1,:), arm(2,:), [0.3 0.3 0.3]);
                
                % Add warning light on top of crane
                light_radius = 0.3;
                S_light = [light_radius, 0, 0; 0, light_radius, 0; 0, 0, 1];
                T_light = [1, 0, 0.5 * app.crane_base_width - 0.5; 0, 1, ground_y + state.pillar_height + 1.5; 0, 0, 1];
                light = T_light * S_light * app.unit_circle;
                
                % Blinking light
                if mod(local_frame, 20) < 10
                    light_color = [1 0.3 0.1]; % Orange
                else
                    light_color = [0.7 0.2 0.2]; % Red
                end
                fill(app.AnimationAxes, light(1,:), light(2,:), light_color);
                
                % Hook position
                hook_local = [0.6; 0.5; 1];
                hook_global = T_arm * R_arm * S_arm * hook_local;
                hook_x = hook_global(1) + state.displace;
                extra_length = 0.3;
                hook_y_top = hook_global(2) + extra_length;
                hook_y_bottom = hook_y_top - (cable_length - extra_length);
                
                % Draw cable
                line(app.AnimationAxes, [hook_x, hook_x], [hook_y_top, hook_y_bottom], 'Color', 'k', 'LineWidth', 2);
                
                % Draw hook
                hook_size = 0.4;
                hook_curve = 0.2;
                hook_points_x = [hook_x, hook_x, hook_x - hook_size/2, hook_x - hook_size, hook_x - hook_size + hook_curve];
                hook_points_y = [hook_y_bottom, hook_y_bottom - hook_size/2, hook_y_bottom - hook_size/2, hook_y_bottom - hook_size/3, hook_y_bottom];
                plot(app.AnimationAxes, hook_points_x, hook_points_y, 'k-', 'LineWidth', 3);
                
                % Object (with swing and tilt) - only if being held
                if object_held
                    pivot_x = hook_x;
                    pivot_y = hook_y_bottom;
                    swing_theta = (state.swing) * deg2rad(5) * sin(2 * pi * local_frame / 60);
                    tilt_rad = deg2rad(state.tilt);
                    
                    S_obj = [app.object_width, 0, 0; 0, app.object_height, 0; 0, 0, 1];
                    T_obj = [1, 0, pivot_x - app.object_width/2; 0, 1, hook_y_bottom - app.object_height; 0, 0, 1];
                    T_to_origin = [1, 0, -pivot_x; 0, 1, -pivot_y; 0, 0, 1];
                    R_swing = [cos(swing_theta), -sin(swing_theta), 0; sin(swing_theta), cos(swing_theta), 0; 0, 0, 1];
                    R_tilt = [cos(tilt_rad), -sin(tilt_rad), 0; sin(tilt_rad), cos(tilt_rad), 0; 0, 0, 1];
                    T_back = [1, 0, pivot_x; 0, 1, pivot_y; 0, 0, 1];
                    object = T_back * R_swing * R_tilt * T_to_origin * T_obj * S_obj * app.unit_rect;
                    fill(app.AnimationAxes, object(1,:), object(2,:), [0.8 0.2 0.2]);
                end


                % Status information
                status_text = sprintf('Command: %s (%d/%d) - Progress: %.0f%%', ...
                    app.commands{current_command_index}, current_command_index, length(app.commands), progress*100);
                title(app.AnimationAxes, status_text);
                
                drawnow;
                pause(0.001);
            end
            
            % Re-enable UI after animation
            app.SelectCommandsDropDown.Enable = 'on';
            app.ParameterEditField.Enable = 'on';
            app.AddCommandButton.Enable = 'on';
            app.RemoveCommandButton.Enable = 'on';
            app.StartAnimationButton.Enable = 'on';
            
            % Final status
            if ~isempty(app.placed_objects)
                message = sprintf('Animation complete! Objects placed on ground: %d', length(app.placed_objects));
            else
                message = 'Animation complete!';
            end
            
            title(app.AnimationAxes, message);
        end
        
        function updateParameterField(app)
            selectedCommand = app.SelectCommandsDropDown.Value;
            
            % Check if the command needs a parameter
            switch selectedCommand
                case {'lift', 'place', 'swingobject'}
                    app.ParameterEditField.Enable = 'off';  % These don't need parameters
                otherwise
                    app.ParameterEditField.Enable = 'on';   % These need parameters
            end
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % Initialize current state
            app.current_state.displace = 0;
            app.current_state.arm_angle = 0;
            app.current_state.arm_scale = 1;
            app.current_state.swing = false;
            app.current_state.tilt = 0;
            app.current_state.pillar_height = 8;

    % Set dropdown items with available commands
    app.SelectCommandsDropDown.Items = {'lift', 'displace', 'place', 'rotatearm', 'extendarm', 'swingobject', 'raisepillar', 'tiltobject'};
    app.SelectCommandsDropDown.Value = 'lift';
                
            % Initialize drawing resources
            initializeAnimationResources(app);
            
            % Setup animation axes
            axis(app.AnimationAxes, 'equal');
            axis(app.AnimationAxes, [-5 25 -1 18]);
            app.AnimationAxes.Title.String = 'Crane Animation';
            app.AnimationAxes.Color = [0.8 0.95 1]; % Sky color
            
            % Check parameter field based on selected command
            updateParameterField(app);        
        end

        % Button pushed function: AddCommandButton
        function AddCommandButtonPushed(app, event)
             % Get selected command
            selectedCommand = app.SelectCommandsDropDown.Value;
            cmd_idx = length(app.commands) + 1;
            
            % Store command
            app.commands{cmd_idx} = selectedCommand;
            
            % Store the parameter and the index
            switch selectedCommand
                case 'rotatearm'
                    angle = app.ParameterEditField.Value;
                    app.params(cmd_idx).type = 'rotatearm';
                    app.params(cmd_idx).start = app.current_state.arm_angle;
                    app.params(cmd_idx).target = angle;
                    app.current_state.arm_angle = angle; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = sprintf('rotatearm: %.1f deg', angle);

                case 'extendarm'
                    scale = app.ParameterEditField.Value;
                    app.params(cmd_idx).type = 'extendarm';
                    app.params(cmd_idx).start = app.current_state.arm_scale;
                    app.params(cmd_idx).target = scale;
                    app.current_state.arm_scale = scale; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = sprintf('extendarm: %.1f', scale);

                case 'swingobject'
                    app.params(cmd_idx).type = 'swingobject';
                    app.params(cmd_idx).start = app.current_state.swing;
                    app.params(cmd_idx).target = true;
                    app.current_state.swing = true; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = 'swingobject';

                case 'tiltobject'
                    tilt = app.ParameterEditField.Value;
                    app.params(cmd_idx).type = 'tiltobject';
                    app.params(cmd_idx).start = app.current_state.tilt;
                    app.params(cmd_idx).target = tilt;
                    app.current_state.tilt = tilt; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = sprintf('tiltobject: %.1f deg', tilt);

                case 'raisepillar'
                    height = app.ParameterEditField.Value;
                    app.params(cmd_idx).type = 'raisepillar';
                    app.params(cmd_idx).start = app.current_state.pillar_height;
                    app.params(cmd_idx).target = height;
                    app.current_state.pillar_height = height; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = sprintf('raisepillar: %.1f', height);

                case 'displace'
                    dist = app.ParameterEditField.Value;
                    app.params(cmd_idx).type = 'displace';
                    app.params(cmd_idx).start = app.current_state.displace;
                    app.params(cmd_idx).target = dist;
                    app.current_state.displace = dist; % Update current
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = sprintf('displace: %.1f', dist);

                case 'lift'
                    app.params(cmd_idx).type = 'lift';
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = 'lift';
                    
                case 'place'
                    app.params(cmd_idx).type = 'place';
                    app.params(cmd_idx).place_x = app.current_state.displace + 0.5 * app.crane_base_width - 0.5 + ...
                        app.crane_arm_length * app.current_state.arm_scale * 0.6 * cos(deg2rad(app.current_state.arm_angle));
                    
                    % Add to list box
                    app.YourcommandsListBox.Items{end+1} = 'place';
            end
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
   if isempty(app.commands)
                title(app.AnimationAxes, 'No commands to animate');
                return;
            end
            
            runAnimation(app);   
        end

        % Value changed function: SelectCommandsDropDown
        function SelectCommandsDropDownValueChanged(app, event)
            updateParameterField(app);
        end

        % Button pushed function: RemoveCommandButton
        function RemoveCommandButtonPushed(app, event)
             % Get the selected item index
            selectedIndex = app.YourcommandsListBox.Value;
            
            % If nothing selected, exit
            if isempty(selectedIndex)
                return;
            end
            
            % Remove item from lists
            app.YourcommandsListBox.Items(selectedIndex) = [];
            app.commands(selectedIndex) = [];
            
            % Remove from params array - need to rebuild it due to indexing
            if ~isempty(app.params) && length(app.params) >= selectedIndex
                new_params = struct([]);
                
                % Copy all parameters except the deleted one
                idx = 1;
                for i = 1:length(app.params)
                    if i ~= selectedIndex
                        new_params(idx) = app.params(i);
                        idx = idx + 1;
                    end
                end
                
                app.params = new_params;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnimationAxes = uiaxes(app.UIFigure);
            title(app.AnimationAxes, 'The Crane animation axes')
            xlabel(app.AnimationAxes, 'X')
            ylabel(app.AnimationAxes, 'Y')
            zlabel(app.AnimationAxes, 'Z')
            app.CRANEANIMATIONSDESIGNINGLabel = uilabel(app.UIFigure);
            app.CRANEANIMATIONSDESIGNINGLabel.Text = 'CRANE ANIMATIONS DESIGNING';
            app.SelectCommandsDropDownLabel = uilabel(app.UIFigure);
            app.SelectCommandsDropDownLabel.Text = 'Select  Commands ';
            app.SelectCommandsDropDown = uidropdown(app.UIFigure);
            app.SelectCommandsDropDown.Items = {''};
            app.SelectCommandsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCommandsDropDownValueChanged, true);
            app.SelectCommandsDropDown.Value = '';
            app.ParameterEditFieldLabel = uilabel(app.UIFigure);
            app.ParameterEditFieldLabel.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddCommandButton = uibutton(app.UIFigure, 'push');
            app.AddCommandButton.ButtonPushedFcn = createCallbackFcn(app, @AddCommandButtonPushed, true);
            app.AddCommandButton.Text = 'Add Command';
            app.YourcommandsListBoxLabel = uilabel(app.UIFigure);
            app.YourcommandsListBoxLabel.Text = 'Your commands';
            app.YourcommandsListBox = uilistbox(app.UIFigure);
            app.YourcommandsListBox.Items = {''};
            app.YourcommandsListBox.Value = '';
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation ';
            app.RemoveCommandButton = uibutton(app.UIFigure, 'push');
            app.RemoveCommandButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCommandButtonPushed, true);
            app.RemoveCommandButton.Text = 'Remove Command';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end