classdef play < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IDENTIFYTHETRANSFORMATIONSLabel  matlab.ui.control.Label
        ChainListBox               matlab.ui.control.ListBox
        ListBoxLabel               matlab.ui.control.Label
        SelectedOpsListBox         matlab.ui.control.ListBox
        SelectedCommandsLabel      matlab.ui.control.Label
        RemoveLastButton           matlab.ui.control.Button
        InstructionTextArea        matlab.ui.control.TextArea
        InstructionsTextAreaLabel  matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        ApplyInverseButton         matlab.ui.control.Button
        InverseOpDropDown          matlab.ui.control.DropDown
        InverseDropdownLabel       matlab.ui.control.Label
        StartTransformButton       matlab.ui.control.Button
        NumStepsEditField          matlab.ui.control.NumericEditField
        NoofStepsLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        shape                           % Original coordinates of the shape
        original_shape
        transformations
        translation_vector
        menu_list
        num_steps
        chain_indices
        applied_chain
        applied_names
        current_shape
        final_shape
        user_chain
        user_selected_ops % Indices chosen by user
        game_phase % 0: initial, 1: after transformation, 2: user reversing
        tolerance = 1e-1
    end
    
    methods (Access = private)
        
         function setupGame(app)
            % Set up shape and transformations
            app.shape = [-2 2 2 -2; -2 -2 2 2]; % Doubled size (from [-1 1 1 -1; -1 -1 1 1])
            app.original_shape = app.shape;
            app.transformations = {
                [-1 0; 0 1],            'Reflect Y-axis';     % (Proper) Reflection about Y-axis (flips x)
                [1 0; 0 -1],            'Reflect X-axis';     % Reflection about X-axis (flips y)
                [cos(pi/6) -sin(pi/6); sin(pi/6) cos(pi/6)], 'Rotate 30°';
                [1.5 0; 0 1.5],         'Scale x1.5';
                [1 0.7; 0 1],           'Shear X';
                [1 0; 0.7 1],           'Shear Y';
                eye(2),                 'Translate';          % Placeholder, apply separately
            };
            app.translation_vector = [2; 1];
            app.menu_list = { ...
                '1 = Reflect Y-axis', ...
                '2 = Reflect X-axis', ...
                '3 = Rotate 30°', ...
                '4 = Scale x1.5', ...
                '5 = Shear X', ...
                '6 = Shear Y', ...
                '7 = Translate (reverse direction)'};
            
            % Set DropDown options
            app.InverseOpDropDown.Items = { ...
                'Reflect Y-axis', ...
                'Reflect X-axis', ...
                'Rotate 30°', ...
                'Scale x1.5', ...
                'Shear X', ...
                'Shear Y', ...
                'Translate (reverse direction)'};
            
            % Reset user progress and UI
            app.num_steps = 0;
            app.chain_indices = [];
            app.applied_chain = {};
            app.applied_names = {};
            app.current_shape = app.original_shape;
            app.final_shape = [];
            app.user_chain = {};
            app.user_selected_ops = [];
            app.ChainListBox.Items = {};
            app.SelectedOpsListBox.Items = {};
            app.InstructionTextArea.Value = "Welcome! Select the number of transformations and click Start.";
            app.game_phase = 0;

            cla(app.UIAxes);

             % Add coordinate reference lines
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
            plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
            
            % Draw the square with more vibrant color
            fill(app.UIAxes, app.original_shape(1,:), app.original_shape(2,:), 'b', 'FaceAlpha', 0.8);
            
            axis(app.UIAxes, [-10 10 -10 10]);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end

        function animateTransform(app, shapeA, shapeB, color)
            % Animate from shapeA to shapeB
            for f = 1:10
                 alpha = f / 10;
                interp_shape = (1-alpha)*shapeA + alpha*shapeB;
                cla(app.UIAxes);
                
                % Add coordinate reference lines
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
                plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
                
                % Enhanced fill with opacity
                fill(app.UIAxes, interp_shape(1,:), interp_shape(2,:), color, 'FaceAlpha', 0.8);
                
                axis(app.UIAxes, [-10 10 -10 10]);
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                drawnow;
                pause(0.2); % Animation speed
            end
        end
        
        function animateLinearTransform(app, current_shape, T, color)
            % Animate a linear transformation
            for f = 1:10
               alpha = f / 10;
                interp_T = (1 - alpha) * eye(2) + alpha * T;
                interp_shape = interp_T * current_shape;
                cla(app.UIAxes);
                
                % Add coordinate reference lines
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
                plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
                
                % Enhanced fill with opacity
                fill(app.UIAxes, interp_shape(1,:), interp_shape(2,:), color, 'FaceAlpha', 0.8);
                
                axis(app.UIAxes, [-10 10 -10 10]);
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                drawnow;
                pause(0.2);
            end
        end
        
        function animateTranslation(app, current_shape, translation_vector, color)
            % Animate a translation
            for f = 1:10
                alpha = f / 10;
                interp_shape = current_shape + alpha * translation_vector;
                cla(app.UIAxes);
                
                % Add coordinate reference lines
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
                plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
                
                % Enhanced fill with opacity
                fill(app.UIAxes, interp_shape(1,:), interp_shape(2,:), color, 'FaceAlpha', 0.8);
                
                axis(app.UIAxes, [-10 10 -10 10]);
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                drawnow;
                pause(0.2);
            end
        end
        
        function updateChainListBox(app)
            % Display the chain of transformation names
            app.ChainListBox.Items = app.applied_names;
        end
        
        function updateSelectedOpsListBox(app)
            % Display the user's selected inverse operations without numbering
    items = {};
    for i = 1:length(app.user_selected_ops)
        idx = app.user_selected_ops(i);
        % Extract only the operation name without the number prefix
        fullText = app.menu_list{idx};
        % Find position after the equals sign and space
        eqPos = strfind(fullText, '=');
        if ~isempty(eqPos)
            % Extract only the part after "= "
            commandName = strtrim(fullText(eqPos+1:end));
            items{end+1} = commandName;
        else
            % Fallback in case format changes
            items{end+1} = fullText;
        end
    end
    app.SelectedOpsListBox.Items = items;
        end

        function resetUserReversal(app)
            app.user_chain = {};
            app.user_selected_ops = [];
            app.SelectedOpsListBox.Items = {};
            app.InstructionTextArea.Value = "Now select the inverse operations to reverse the transformation chain.";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
               setupGame(app);
        end

        % Button pushed function: StartTransformButton
        function StartTransformButtonPushed(app, event)
             app.InstructionTextArea.Value = "Animating transformation sequence...";
            app.num_steps = app.NumStepsEditField.Value;
            max_steps = size(app.transformations,1);
            if isnan(app.num_steps) || app.num_steps < 1 || app.num_steps > max_steps
                app.InstructionTextArea.Value = ...
                    sprintf("Please select a number of transformations between 1 and %d.", max_steps);
                return;
            end
            app.chain_indices = randperm(max_steps, app.num_steps);
            app.applied_chain = {};
            app.applied_names = {};
            app.current_shape = app.original_shape;
            
            cla(app.UIAxes);
            
            % Add coordinate reference lines
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
            plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
            
            % Enhanced fill with opacity
            fill(app.UIAxes, app.current_shape(1,:), app.current_shape(2,:), 'b', 'FaceAlpha', 0.8);
            
            axis(app.UIAxes, [-10 10 -10 10]);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            
            pause(0.3);
            
            % Clear the ChainListBox before starting
            app.ChainListBox.Items = {};
            
            % Animate the transformations
            for i = 1:app.num_steps
                idx = app.chain_indices(i);
                T = app.transformations{idx,1};
                name = app.transformations{idx,2};
                if strcmp(name, 'Translate')
                    app.animateTranslation(app.current_shape, app.translation_vector, 'b');
                    app.current_shape = app.current_shape + app.translation_vector;
                else
                    app.animateLinearTransform(app.current_shape, T, 'b');
                    app.current_shape = T * app.current_shape;
                end
                app.applied_chain{end+1} = T;
                app.applied_names{end+1} = name;
                % Do NOT update ChainListBox here to keep it hidden
            end
            app.final_shape = app.current_shape;
            app.InstructionTextArea.Value = "Now select the inverse operations to reverse the transformation chain, in reverse order.";
            app.resetUserReversal();
            app.game_phase = 1;
        end

        % Button pushed function: ApplyInverseButton
        function ApplyInverseButtonPushed2(app, event)
          % Only allowed if in reversal phase
            if app.game_phase ~= 1
                app.InstructionTextArea.Value = "Start the transformation sequence first!";
                return;
            end
            idx = app.InverseOpDropDown.Value;
            idx_num = find(strcmp(app.InverseOpDropDown.Items, idx));
            if isempty(idx_num)
                app.InstructionTextArea.Value = "Please choose a valid inverse operation.";
                return;
            end
            if length(app.user_selected_ops) >= app.num_steps
                app.InstructionTextArea.Value = "You have already selected the required number of operations.";
                return;
            end
            app.user_selected_ops(end+1) = idx_num;
            % Add the corresponding inverse transformation
            if idx_num == 7
                app.user_chain{end+1} = -app.translation_vector;
            else
                app.user_chain{end+1} = inv(app.transformations{idx_num,1});
            end
            app.updateSelectedOpsListBox();
            app.InstructionTextArea.Value = sprintf("Selected %d/%d steps. Continue selecting or click Reset to start over.", length(app.user_selected_ops), app.num_steps);
            % If all selected, animate the reversal and check result
            if length(app.user_selected_ops) == app.num_steps
                app.InstructionTextArea.Value = "Animating your reversal...";
                pause(0.5);
                % Animate reversal
                current_attempt = app.final_shape;
                for i = 1:app.num_steps
                    T = app.user_chain{i};
                    if isvector(T)
                        app.animateTranslation(current_attempt, T, 'g');
                        current_attempt = current_attempt + T;
                    else
                        app.animateLinearTransform(current_attempt, T, 'g');
                        current_attempt = T * current_attempt;
                    end
                end
                % Check result
                difference = norm(current_attempt - app.original_shape);
                
                cla(app.UIAxes);
                
                % Add coordinate reference lines
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [0 0], [-10 10], 'k--'); % Y-axis
                plot(app.UIAxes, [-10 10], [0 0], 'k--'); % X-axis
                
                % Enhanced fill with opacity
                fill(app.UIAxes, current_attempt(1,:), current_attempt(2,:), 'g', 'FaceAlpha', 0.8);
                
                axis(app.UIAxes, [-10 10 -10 10]);
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                
                % NOW reveal the actual transformation chain
                app.updateChainListBox();
                
                if difference < app.tolerance
                    app.InstructionTextArea.Value = sprintf("🎉 SUCCESS! You accurately reversed all transformations.\n\nActual Transform Chain (in order) is now shown in the list box.");
                else
                    app.InstructionTextArea.Value = sprintf("INCORRECT! Try again.\n\nActual Transform Chain (in order) is now shown in the list box: %s", strjoin(app.applied_names, ' -> '));
                end
                app.game_phase = 2; % End phase
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
             setupGame(app);
        end

        % Button pushed function: RemoveLastButton
        function RemoveLastButtonPushed(app, event)
            if isempty(app.user_selected_ops)
                app.InstructionTextArea.Value = "No operations to remove.";
                return;
            end
            app.user_selected_ops(end) = [];
            app.user_chain(end) = [];
            app.updateSelectedOpsListBox();
            app.InstructionTextArea.Value = sprintf("Step removed. %d/%d steps remain to select.", length(app.user_selected_ops), app.num_steps);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'LINEAR ALGEBRA BASED TRANSFORMATIONS')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NoofStepsLabel = uilabel(app.UIFigure);
            app.NoofStepsLabel.Text = 'No. of Steps';
            app.NumStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTransformButton = uibutton(app.UIFigure, 'push');
            app.StartTransformButton.ButtonPushedFcn = createCallbackFcn(app, @StartTransformButtonPushed, true);
            app.StartTransformButton.Text = 'Transform Start';
            app.InverseDropdownLabel = uilabel(app.UIFigure);
            app.InverseDropdownLabel.Text = 'Inverse Dropdown';
            app.InverseOpDropDown = uidropdown(app.UIFigure);
            app.ApplyInverseButton = uibutton(app.UIFigure, 'push');
            app.ApplyInverseButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyInverseButtonPushed2, true);
            app.ApplyInverseButton.Text = 'Add Command';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = 'Reset ';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = {'Instructions..'; ' Text Area......'};
            app.InstructionTextArea = uitextarea(app.UIFigure);
            app.RemoveLastButton = uibutton(app.UIFigure, 'push');
            app.RemoveLastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastButtonPushed, true);
            app.RemoveLastButton.Text = 'Remove Choice';
            app.SelectedCommandsLabel = uilabel(app.UIFigure);
            app.SelectedCommandsLabel.Text = {'Selected'; 'Commands'};
            app.SelectedOpsListBox = uilistbox(app.UIFigure);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ChainListBox = uilistbox(app.UIFigure);
            app.IDENTIFYTHETRANSFORMATIONSLabel = uilabel(app.UIFigure);
            app.IDENTIFYTHETRANSFORMATIONSLabel.Text = 'IDENTIFY THE TRANSFORMATIONS ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = play

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end