classdef rocket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RemoveCommandsButton         matlab.ui.control.Button
        YourCommandsListBox          matlab.ui.control.ListBox
        YourCommandsListBoxLabel     matlab.ui.control.Label
        RocketAnimationLabel         matlab.ui.control.Label
        ParametersEditField          matlab.ui.control.NumericEditField
        ParametersEditFieldLabel     matlab.ui.control.Label
        StartAnimationButton         matlab.ui.control.Button
        AddCommandButton             matlab.ui.control.Button
        SelectCommandsDropDown       matlab.ui.control.DropDown
        SelectCommandsDropDownLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      CommandQueue string = strings(0) % Stores the list of commands
    end
    
    methods (Access = private)


               % Function to start the rocket animation with commands
        function RocketAnimationWithCommands(app, commands)
            app.UIAxes.Color = [0, 0, 0];
            % Initialize figure and axes
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, [-20 20 -20 40]);
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
            
            % Rocket parts initialization (same as your code)
            rocket_body = [0 1 1 0; 0 0 2 2];
            rocket_nose = [0 1 0.5; 2 2 3];
            flame = [0 1 0.5; -0.2 -0.2 -1.0];
            scale_factor = 2;
            rocket_body = rocket_body * scale_factor;
            rocket_nose = rocket_nose * scale_factor;
            flame = flame * scale_factor;

            rocket_position = [0, 0];
            show_flame = true;

            stars_x = rand(1, 50) * 40 - 20;
            stars_y = rand(1, 50) * 60 - 20;
            plot(app.UIAxes, stars_x, stars_y, 'w.', 'MarkerSize', 2);

            % Iterate over the commands
            for i = 1:length(commands)
                parts = strsplit(lower(strtrim(commands{i})));
                if isempty(parts), continue; end
                action = parts{1};

                % Handling different commands
                switch action
                    case 'up'
                        d = app.getDistance(parts);
                        rocket_position = app.translate(app.UIAxes, rocket_body, rocket_nose, flame, rocket_position, 0, d, 0.0001, stars_x, stars_y, show_flame);
                    case 'down'
                        d = app.getDistance(parts);
                        rocket_position = app.translate(app.UIAxes, rocket_body, rocket_nose, flame, rocket_position, 0, -d, 0.0001, stars_x, stars_y, show_flame);
                    case 'left'
                        d = app.getDistance(parts);
                        rocket_position = app.translate(app.UIAxes, rocket_body, rocket_nose, flame, rocket_position, -d, 0, 0.005, stars_x, stars_y, show_flame);
                    case 'right'
                        d = app.getDistance(parts);
                        rocket_position = app.translate(app.UIAxes, rocket_body, rocket_nose, flame, rocket_position, d, 0, 0.005, stars_x, stars_y, show_flame);
                    case 'blastoff'
                        rocket_position = app.translate(app.UIAxes, rocket_body, rocket_nose, flame, rocket_position, 0, 15, 0.00001, stars_x, stars_y, show_flame);
                    case 'grow'
                        rocket_body = rocket_body * 1.5;
                        rocket_nose = rocket_nose * 1.5;
                        flame = flame * 1.5;
                    case 'shrink'
                        rocket_body = rocket_body * 0.5;
                        rocket_nose = rocket_nose * 0.5;
                        flame = flame * 0.5;
                    case 'hideflame'
                        show_flame = false;
                    case 'showflame'
                        show_flame = true;
                    case 'pause'
                        pause_time = app.getDistance(parts);
                        pause(pause_time);
                    case 'rotate'
                        angle = app.getDistance(parts);
                        theta = deg2rad(angle);
                        R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                        rocket_body = R * rocket_body;
                        rocket_nose = R * rocket_nose;
                        flame = R * flame;
                    case 'spin'
                        for k = 1:36
                            theta = deg2rad(10 * k);
                            R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                            body_t = (R * rocket_body) + rocket_position';
                            nose_t = (R * rocket_nose) + rocket_position';
                            flame_t = (R * flame) + rocket_position';

                            cla(app.UIAxes);
                            axis(app.UIAxes, [-20 20 -20 40]);
                            hold(app.UIAxes, 'on');
                            plot(app.UIAxes, stars_x, stars_y, 'w.', 'MarkerSize', 2);
                            app.draw(app.UIAxes, body_t, nose_t, flame_t, show_flame);
                            pause(0.01);
                        end
                end
            end
        end
        
    end
    
    % Helper functions to handle drawing and movement
    methods (Access = private)
        function pos = translate(app,ax, body, nose, flame, pos, dx, dy, speed, sx, sy, show_flame)
            frames = 30;
            new_pos = pos + [dx*5, dy*5];
            for t = 1:frames
                inter = pos + (new_pos - pos) * (t/frames);
                cla(ax); hold(ax, 'on'); axis(ax, [-20 20 -20 40]);
                plot(ax, sx, sy, 'w.', 'MarkerSize', 2);
                app.draw(ax, body + inter', nose + inter', flame + inter', show_flame);
                pause(speed);
            end
            pos = new_pos;
        end
        
        function draw(app, ax, body, nose, flame, show_flame)
            fill(ax, body(1,:), body(2,:), [0.5 0.5 1], 'EdgeColor', 'none');
            fill(ax, nose(1,:), nose(2,:), [1 0 0], 'EdgeColor', 'none');
            if show_flame
                fill(ax, flame(1,:), flame(2,:), [1 0.5 0], 'EdgeColor', 'none');
            end
        end
        
        function d = getDistance(app,parts)
            if length(parts) >= 2
                d = str2double(parts{2});
                if isnan(d), d = 5; end
            else
                d = 5;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.SelectCommandsDropDown.Items = {'up', 'down', 'left', 'right', ...
                                 'blastoff', 'grow', 'shrink', ...
                                 'hideflame', 'showflame', ...
                                 'spin', 'rotate', 'pause'};
    app.YourCommandsListBox.Items = {};
    app.ParametersEditField.Editable = 'on';
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed2(app, event)
              % Launch animation using commands in app.CommandQueue
                commands = app.CommandQueue;
      app.RocketAnimationWithCommands(commands);
        end

        % Button pushed function: AddCommandButton
        function AddCommandButtonPushed(app, event)
            command = app.SelectCommandsDropDown.Value;
param = strtrim(string(app.ParametersEditField.Value));

    
    if ~isempty(param)
        cmd_str = sprintf('%s %s', command, param);
    else
        cmd_str = command;
    end
    
    app.CommandQueue{end+1} = cmd_str;
    app.YourCommandsListBox.Items = app.CommandQueue;

        end

        % Value changed function: SelectCommandsDropDown
        function SelectCommandsDropDownValueChanged(app, event)
          
            selected = app.SelectCommandsDropDown.Value;
    param_required = ismember(selected, {'up','down','left','right','pause','rotate'});
    app.ParametersEditField.Editable = param_required;

    % Allow empty values in the ParamEditField
app.ParametersEditField.AllowEmpty = true;  % This allows clearing the field

    if ~param_required
        app.ParametersEditField.Value = '';
    end
        end

        % Button pushed function: RemoveCommandsButton
        function RemoveCommandsButtonPushed(app, event)
        % Check if there are any commands in the queue
    if ~isempty(app.CommandQueue)
        % Remove the last command from the CommandQueue
        app.CommandQueue(end) = [];  % Remove last command

        % Optionally, update the command list display (e.g., in a UI element like a ListBox)
        app.YourCommandsListBox.Items = cellstr(app.CommandQueue);  % Convert string array to cell array for display
    else
        % Optionally, notify the user that the queue is empty
        uialert(app.UIFigure, 'No commands to remove!', 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rocket Animation Axes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectCommandsDropDownLabel = uilabel(app.UIFigure);
            app.SelectCommandsDropDownLabel.Text = 'Select Commands';
            app.SelectCommandsDropDown = uidropdown(app.UIFigure);
            app.SelectCommandsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCommandsDropDownValueChanged, true);
            app.AddCommandButton = uibutton(app.UIFigure, 'push');
            app.AddCommandButton.ButtonPushedFcn = createCallbackFcn(app, @AddCommandButtonPushed, true);
            app.AddCommandButton.Text = 'Add Command';
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed2, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.ParametersEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.UIFigure, 'numeric');
            app.RocketAnimationLabel = uilabel(app.UIFigure);
            app.RocketAnimationLabel.Text = 'Rocket Animation ';
            app.YourCommandsListBoxLabel = uilabel(app.UIFigure);
            app.YourCommandsListBoxLabel.Text = 'Your Commands';
            app.YourCommandsListBox = uilistbox(app.UIFigure);
            app.YourCommandsListBox.Items = {''};
            app.YourCommandsListBox.Value = '';
            app.RemoveCommandsButton = uibutton(app.UIFigure, 'push');
            app.RemoveCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCommandsButtonPushed, true);
            app.RemoveCommandsButton.Text = 'Remove Commands';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rocket

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end