classdef maze_playing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        YourMovesEditField         matlab.ui.control.EditField
        YourMovesEditFieldLabel    matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
        SubmitMovesButton          matlab.ui.control.Button
        MoveRightButton            matlab.ui.control.Button
        MoveLeftButton             matlab.ui.control.Button
        MoveDownButton             matlab.ui.control.Button
        MoveUpButton               matlab.ui.control.Button
        CommandButtonsLabel        matlab.ui.control.Label
        ViewingTimeEditField       matlab.ui.control.NumericEditField
        ViewingTimeEditFieldLabel  matlab.ui.control.Label
        StartMazeViewingButton     matlab.ui.control.Button
        GenerateMazeButton         matlab.ui.control.Button
        MAZESOLVERGAMELabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    maze           % Matrix of the maze
    startPos       % [row, col] of start position
    endPos         % [row, col] of end position
    currentPos     % [row, col] current player position during animation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             cla(app.UIAxes);
        title(app.UIAxes, 'Click "Generate Maze" to Start!');
        end

        % Button pushed function: GenerateMazeButton
        function GenerateMazeButtonPushed(app, event)
            n = 5; % Size of maze (10x10)
        solvable = false;

        while ~solvable
            app.maze = randi([0 1], n, n); % 0 = path, 1 = wall
            app.startPos = [1, 1];
            app.endPos = [n, n];
            app.maze(1,1) = 0;
            app.maze(n,n) = 0;

            solvable = app.isMazeSolvable(app.maze, app.startPos, app.endPos);
        end

        plotMaze(app, app.maze);
        title(app.UIAxes, 'Maze Generated! Enter View Time.');
        app.ResultLabel.Text = ''; % Clear result text
        app.YourMovesEditField.Value = ""; % Clear previous moves
        end

        % Button pushed function: StartMazeViewingButton
        function StartMazeViewingButtonPushed(app, event)
        viewTime = app.ViewingTimeEditField.Value; % seconds
        title(app.UIAxes, 'Memorize the Maze!');
        pause(viewTime);
        cla(app.UIAxes); % Clear Maze after view
        title(app.UIAxes, 'Now Enter your Move Sequence!');
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
        app.YourMovesEditField.Value = [app.YourMovesEditField.Value,newline, 'up'];
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
        app.YourMovesEditField.Value = [app.YourMovesEditField.Value,newline, 'down'];
        end

        % Button pushed function: MoveLeftButton
        function MoveLeftButtonPushed(app, event)
        app.YourMovesEditField.Value = [app.YourMovesEditField.Value,newline, 'left'];
        end

        % Button pushed function: MoveRightButton
        function MoveRightButtonPushed(app, event)
        app.YourMovesEditField.Value = [app.YourMovesEditField.Value,newline, 'right'];
        end

        % Button pushed function: SubmitMovesButton
        function SubmitMovesButtonPushed(app, event)
    moves = split(app.YourMovesEditField.Value, newline); % Split moves by new lines
    app.currentPos = app.startPos;

    % Redraw maze faintly
    app.plotMaze(app.maze);
    hold(app.UIAxes, 'on');
    playerMarker = plot(app.UIAxes, app.currentPos(2)-0.5, size(app.maze,1)-app.currentPos(1)+0.5, ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

    for i = 1:length(moves)
        move = lower(strtrim(moves{i}));  % Access as a string array element
        newPos = app.currentPos;

        % Process the move
        switch move
            case 'up'
                newPos(1) = newPos(1) - 1;
            case 'down'
                newPos(1) = newPos(1) + 1;
            case 'left'
                newPos(2) = newPos(2) - 1;
            case 'right'
                newPos(2) = newPos(2) + 1;
            otherwise
                disp(['Invalid move: ', move]);
                continue;
        end

        % Check boundaries and walls
        if newPos(1) >=1 && newPos(1) <= size(app.maze,1) && ...
           newPos(2) >=1 && newPos(2) <= size(app.maze,2) && ...
           app.maze(newPos(1), newPos(2)) == 0
            app.currentPos = newPos;
            % Animate
            set(playerMarker, 'XData', app.currentPos(2) - 0.5);
            set(playerMarker, 'YData', size(app.maze,1) - app.currentPos(1) + 0.5);
            pause(0.5);
        else
            disp(['Blocked move: ', move]);
        end
    end

    % Check win condition after the loop
    if isequal(app.currentPos, app.endPos)
        app.ResultLabel.Text = '✅ You WON!';
    else
        app.ResultLabel.Text = '❌ You LOST!';
    end
end


    function plotMaze(app, maze)
        n = size(maze,1);
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');

        for i = 1:n
            for j = 1:n
                if maze(i,j) == 1
                    rectangle(app.UIAxes, 'Position', [j-1, n-i, 1, 1], 'FaceColor', 'k');
                else
                    rectangle(app.UIAxes, 'Position', [j-1, n-i, 1, 1], 'FaceColor', 'w');
                end
            end
        end

        % Start position - Red
        rectangle(app.UIAxes, 'Position', [app.startPos(2)-1, n-app.startPos(1), 1, 1], 'FaceColor', 'r');
        % End position - Green
        rectangle(app.UIAxes, 'Position', [app.endPos(2)-1, n-app.endPos(1), 1, 1], 'FaceColor', 'g');

        axis(app.UIAxes, 'equal');
        axis(app.UIAxes, 'off');
    end

    function solvable = isMazeSolvable(app,maze, startPos, endPos)
        n = size(maze,1);
        visited = zeros(n);
        queue = startPos;

        directions = [0 1; 1 0; 0 -1; -1 0];

        while ~isempty(queue)
            pos = queue(1,:);
            queue(1,:) = [];

            if isequal(pos, endPos)
                solvable = true;
                return;
            end

            for d = 1:4
                newPos = pos + directions(d,:);
                if newPos(1) >=1 && newPos(1) <= n && ...
                   newPos(2) >=1 && newPos(2) <= n && ...
                   maze(newPos(1), newPos(2)) == 0 && ...
                   visited(newPos(1), newPos(2)) == 0
                    queue(end+1,:) = newPos;
                    visited(newPos(1), newPos(2)) = 1;
                end
            end
        end

        solvable = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'The Maze ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MAZESOLVERGAMELabel = uilabel(app.UIFigure);
            app.MAZESOLVERGAMELabel.Text = 'MAZE SOLVER GAME ';
            app.GenerateMazeButton = uibutton(app.UIFigure, 'push');
            app.GenerateMazeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMazeButtonPushed, true);
            app.GenerateMazeButton.Text = 'Generate Maze';
            app.StartMazeViewingButton = uibutton(app.UIFigure, 'push');
            app.StartMazeViewingButton.ButtonPushedFcn = createCallbackFcn(app, @StartMazeViewingButtonPushed, true);
            app.StartMazeViewingButton.Text = 'Start Maze Viewing ';
            app.ViewingTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ViewingTimeEditFieldLabel.Text = 'Viewing Time';
            app.ViewingTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommandButtonsLabel = uilabel(app.UIFigure);
            app.CommandButtonsLabel.Text = 'Command Buttons ';
            app.MoveUpButton = uibutton(app.UIFigure, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.UIFigure, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.MoveLeftButton = uibutton(app.UIFigure, 'push');
            app.MoveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeftButton.Text = 'Move Left';
            app.MoveRightButton = uibutton(app.UIFigure, 'push');
            app.MoveRightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRightButton.Text = 'Move Right';
            app.SubmitMovesButton = uibutton(app.UIFigure, 'push');
            app.SubmitMovesButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitMovesButtonPushed, true);
            app.SubmitMovesButton.Text = 'Submit Moves';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'ResultLabel';
            app.YourMovesEditFieldLabel = uilabel(app.UIFigure);
            app.YourMovesEditFieldLabel.Text = 'Your  Moves';
            app.YourMovesEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maze_playing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end