classdef sound_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SOUNDRESPONSIVEFLIPBOOKLabel  matlab.ui.control.Label
        SelectShapeDropDown           matlab.ui.control.DropDown
        SelectShapeDropDownLabel      matlab.ui.control.Label
        InfoLabel                     matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        FileNameLabel                 matlab.ui.control.Label
        SelectNewFileButton           matlab.ui.control.Button
        PlayPauseButton               matlab.ui.control.Button
        SelectAudioButton             matlab.ui.control.Button
        WaveformAxes                  matlab.ui.control.UIAxes
        StarAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioData
        fs
        audioEnergy
        audioDifference
        audioAverage
        baseObject
        frameTime
        frameDelays
        numChunks
        colors
        player
        currentFrame = 1
        animationTimer
        startTime
        isPlaying = false
        objectPlot
        waveIndicator
        shapeList = {'Star', 'Triangle', 'Pentagon', 'Hexagon', 'Circle'}
        selectedShape = 'Star'
        loadedFile = ''
    end
    
    methods (Access = private)
        
         % --- Audio loading and preprocessing ---
        function loadAndProcessAudio(app, filepath, filename)
            [audioData, fs] = audioread(fullfile(filepath, filename));
            if size(audioData, 2) > 1
                audioData = mean(audioData, 2);
            end
            audioData = audioData / max(abs(audioData));
            app.audioData = audioData;
            app.fs = fs;
            chunkSize = 1024;
            app.numChunks = floor(length(audioData)/chunkSize);

            % Feature extraction
            app.audioEnergy = zeros(app.numChunks, 1);
            app.audioDifference = zeros(app.numChunks, 1);
            app.audioAverage = zeros(app.numChunks, 1);

            for i = 1:app.numChunks
                startIdx = (i-1)*chunkSize + 1;
                endIdx = min(startIdx + chunkSize - 1, length(audioData));
                chunk = audioData(startIdx:endIdx);
                app.audioEnergy(i) = mean(chunk.^2);
                if i > 1
                    app.audioDifference(i) = abs(app.audioEnergy(i) - app.audioEnergy(i-1));
                end
                app.audioAverage(i) = mean(abs(chunk));
            end

            % Normalize and enhance
            app.audioEnergy = (app.audioEnergy - min(app.audioEnergy)) / (max(app.audioEnergy) - min(app.audioEnergy) + eps);
            app.audioDifference = (app.audioDifference - min(app.audioDifference)) / (max(app.audioDifference) - min(app.audioDifference) + eps);
            app.audioAverage = (app.audioAverage - min(app.audioAverage)) / (max(app.audioAverage) - min(app.audioAverage) + eps);

            app.audioEnergy = app.audioEnergy.^1.2;
            app.audioDifference = app.audioDifference.^1.3;

            % Frame timing and colors
            app.frameTime = linspace(0, length(audioData)/fs, app.numChunks+1);
            app.frameTime = app.frameTime(1:end-1);
            minDelay = 0.01; maxDelay = 0.03; % Animation will be much smoother and more responsive with lower maxDelay
            app.frameDelays = maxDelay - (app.audioEnergy * (maxDelay - minDelay));
            app.colors = [linspace(0.7, 1, app.numChunks)', linspace(0.5, 0.9, app.numChunks)', linspace(0, 0.4, app.numChunks)'];

            % Display file info
            app.FileNameLabel.Text = ['Loaded File: ', filename];
            app.StatusLabel.Text = 'Status: Ready';
            app.loadedFile = filename;
            app.currentFrame = 1; % reset on reload
            app.plotWaveform();
            app.createShape();
        end

        % --- Plot audio waveform and indicator ---
        function plotWaveform(app)
            cla(app.WaveformAxes);
            t = (0:length(app.audioData)-1) / app.fs;
            plot(app.WaveformAxes, t, app.audioData, 'b');
            hold(app.WaveformAxes, 'on');
            app.waveIndicator = plot(app.WaveformAxes, [0 0], [-1 1], 'r', 'LineWidth', 2);
            hold(app.WaveformAxes, 'off');
            app.WaveformAxes.XLim = [0 t(end)];
            app.WaveformAxes.YLim = [-1 1];
            app.WaveformAxes.XLabel.String = 'Time (s)';
            app.WaveformAxes.YLabel.String = 'Amplitude';
            app.WaveformAxes.Title.String = ['Audio: "', app.loadedFile, '"'];
        end

        % --- Shape Creation according to dropdown ---
        function createShape(app)
            shape = app.SelectShapeDropDown.Value;
            switch shape
                case 'Star'
                    numPoints = 5; innerRadius = 0.4; outerRadius = 1.0;
                    theta = linspace(0, 2*pi, 2*numPoints+1);
                    theta = theta(1:end-1);
                    radius = zeros(size(theta));
                    radius(1:2:end) = outerRadius;
                    radius(2:2:end) = innerRadius;
                    shapeX = radius .* cos(theta);
                    shapeY = radius .* sin(theta);
                case 'Triangle'
                    theta = linspace(0, 2*pi, 4); theta(end) = [];
                    shapeX = cos(theta); shapeY = sin(theta);
                case 'Pentagon'
                    theta = linspace(0, 2*pi, 6); theta(end) = [];
                    shapeX = cos(theta); shapeY = sin(theta);
                case 'Hexagon'
                    theta = linspace(0, 2*pi, 7); theta(end) = [];
                    shapeX = cos(theta); shapeY = sin(theta);
                case 'Circle'
                    theta = linspace(0, 2*pi, 100);
                    shapeX = cos(theta); shapeY = sin(theta);
                otherwise
                    % Default to star
                    numPoints = 5; innerRadius = 0.4; outerRadius = 1.0;
                    theta = linspace(0, 2*pi, 2*numPoints+1);
                    theta = theta(1:end-1);
                    radius = zeros(size(theta));
                    radius(1:2:end) = outerRadius;
                    radius(2:2:end) = innerRadius;
                    shapeX = radius .* cos(theta);
                    shapeY = radius .* sin(theta);
            end
            app.baseObject = [shapeX; shapeY];

            cla(app.StarAxes);
            app.objectPlot = fill(app.StarAxes, app.baseObject(1,:), app.baseObject(2,:), app.colors(1,:), 'FaceAlpha', 0.6, 'EdgeColor', 'none');
            axis(app.StarAxes, [-3 3 -3 3]);
            app.StarAxes.XLim = [-3 3];
            app.StarAxes.YLim = [-3 3];
            app.StarAxes.DataAspectRatio = [1 1 1];
            grid(app.StarAxes, 'on');
            app.StarAxes.XLabel.String = 'X';
            app.StarAxes.YLabel.String = 'Y';
            app.StarAxes.Title.String = [shape, ' Animation'];
        end

        % --- Animation update function ---
        function updateAnimation(app, ~, ~)
            if app.currentFrame > app.numChunks || ~isplaying(app.player)
                stop(app.animationTimer);
                app.StatusLabel.Text = 'Status: Done';
                app.isPlaying = false;
                return;
            end

            % Get features
            energy = app.audioEnergy(app.currentFrame);
            difference = app.audioDifference(app.currentFrame);
            average = app.audioAverage(app.currentFrame);

            % Transformations
            scale = 0.5 + 2.5 * energy;
            scaleMatrix = [scale 0; 0 scale];
            angle = average * 2 * pi;
            rotMatrix = [cos(angle) -sin(angle); sin(angle) cos(angle)];
            shearX = difference * 0.8;
            shearY = difference * 0.5;
            shearMatrix = [1 shearX; shearY 1];
            transformMatrix = rotMatrix * shearMatrix * scaleMatrix;
            transformedObject = transformMatrix * app.baseObject;

            % Update star plot
            set(app.objectPlot, 'XData', transformedObject(1,:), 'YData', transformedObject(2,:), 'FaceColor', app.colors(app.currentFrame,:));
            app.StarAxes.Title.String = sprintf('%s Animation (Size: %.2f, Rotation: %.1f°)', app.SelectShapeDropDown.Value, scale, angle*180/pi);

            % Update waveform indicator
            elapsedTime = toc(app.startTime);
            set(app.waveIndicator, 'XData', [elapsedTime, elapsedTime], 'YData', [-1 1]);
            app.WaveformAxes.XLim = [0 app.frameTime(end)];

            % Advance frame, schedule next callback
            delay = app.frameDelays(app.currentFrame);
            app.currentFrame = app.currentFrame + 1;
            if app.currentFrame <= app.numChunks
                stop(app.animationTimer);
                app.animationTimer = timer('ExecutionMode','singleShot','TimerFcn',@(~,~)app.updateAnimation(),'StartDelay',delay);
                start(app.animationTimer);
            end
        end

        % --- Start or restart animation and audio ---
        function startAnimation(app)
            if isempty(app.audioData)
                app.StatusLabel.Text = 'Status: No file loaded';
                return;
            end

            % Stop any previous player/timer
            if ~isempty(app.player) && isplaying(app.player)
                stop(app.player);
            end
            if ~isempty(app.animationTimer) && isvalid(app.animationTimer)
                stop(app.animationTimer);
                delete(app.animationTimer);
            end

            % Reset animation state
            app.currentFrame = 1;
            app.plotWaveform();
            app.createShape();

            % Create player and timer
            app.player = audioplayer(app.audioData, app.fs);
            app.startTime = tic;

            app.animationTimer = timer('ExecutionMode','singleShot','TimerFcn',@(~,~)app.updateAnimation(), ...
                'StartDelay', app.frameDelays(1));
            app.isPlaying = true;
            app.StatusLabel.Text = 'Status: Playing';

            play(app.player);
            start(app.animationTimer);
        end

        % --- Pause animation and audio ---
        function pauseAnimation(app)
            if ~isempty(app.player) && isplaying(app.player)
                pause(app.player);
            end
            if ~isempty(app.animationTimer) && isvalid(app.animationTimer)
                stop(app.animationTimer);
            end
            app.isPlaying = false;
            app.StatusLabel.Text = 'Status: Paused';
        end

        % --- Resume animation and audio ---
        function resumeAnimation(app)
            if ~isempty(app.player)
                resume(app.player);
            end
            if ~isempty(app.animationTimer) && isvalid(app.animationTimer)
                start(app.animationTimer);
            else
                app.animationTimer = timer('ExecutionMode','singleShot','TimerFcn',@(~,~)app.updateAnimation(), ...
                    'StartDelay', app.frameDelays(app.currentFrame));
                start(app.animationTimer);
            end
            app.isPlaying = true;
            app.StatusLabel.Text = 'Status: Playing';
        end

        % --- Cleanup on app close ---
        function cleanup(app)
            if ~isempty(app.player) && isplaying(app.player)
                stop(app.player);
            end
            if ~isempty(app.animationTimer) && isvalid(app.animationTimer)
                stop(app.animationTimer);
                delete(app.animationTimer);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.SelectShapeDropDown.Items = app.shapeList;
            app.SelectShapeDropDown.Value = app.shapeList{1};
            app.InfoLabel.Text = 'Simple Sound-Responsive Animation. The shape responds to audio volume and changes.';
            app.FileNameLabel.Text = 'Loaded File: ';
            app.StatusLabel.Text = 'Status: Ready';
        end

        % Button pushed function: SelectAudioButton
        function SelectAudioButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'}, 'Select an audio file');
                if isequal(fileName, 0)
                    return;
                end
                app.loadAndProcessAudio(filePath, fileName);
                app.StatusLabel.Text = 'Status: File loaded. Ready to play.';
        
        end

        % Button pushed function: SelectNewFileButton
        function SelectNewFileButtonPushed(app, event)
           app.cleanup();
           app.SelectAudioButtonPushed();
        end

        % Value changed function: SelectShapeDropDown
        function SelectShapeDropDownValueChanged(app, event)
             if isempty(app.baseObject)
                return;
            end
            app.createShape();        
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
             if isempty(app.audioData)
                app.StatusLabel.Text = 'Status: No file loaded';
                return;
            end
            if ~app.isPlaying
                app.startAnimation();
            else
                app.pauseAnimation();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.cleanup();
            delete(app);
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StarAxes = uiaxes(app.UIFigure);
            title(app.StarAxes, 'Shape Transformation Axes')
            xlabel(app.StarAxes, 'X')
            ylabel(app.StarAxes, 'Y')
            zlabel(app.StarAxes, 'Z')
            app.WaveformAxes = uiaxes(app.UIFigure);
            title(app.WaveformAxes, 'Sound''s Wave Form')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.SelectAudioButton = uibutton(app.UIFigure, 'push');
            app.SelectAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioButtonPushed, true);
            app.SelectAudioButton.Text = 'Select Audio File';
            app.PlayPauseButton = uibutton(app.UIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play / Pause';
            app.SelectNewFileButton = uibutton(app.UIFigure, 'push');
            app.SelectNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewFileButtonPushed, true);
            app.SelectNewFileButton.Text = 'Select New File';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'FileNameLabel';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'InfoLabel';
            app.SelectShapeDropDownLabel = uilabel(app.UIFigure);
            app.SelectShapeDropDownLabel.Text = 'Select Shape';
            app.SelectShapeDropDown = uidropdown(app.UIFigure);
            app.SelectShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectShapeDropDownValueChanged, true);
            app.SOUNDRESPONSIVEFLIPBOOKLabel = uilabel(app.UIFigure);
            app.SOUNDRESPONSIVEFLIPBOOKLabel.Text = 'SOUND RESPONSIVE FLIPBOOK ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end